classdef shaft_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SolveButton                     matlab.ui.control.Button
        CriticalFractureToughnessEditField  matlab.ui.control.NumericEditField
        CriticalFractureToughnessEditFieldLabel  matlab.ui.control.Label
        YieldStrengthMPaEditField       matlab.ui.control.NumericEditField
        YieldStrengthMPaEditFieldLabel  matlab.ui.control.Label
        MaterialPropertiesLabel         matlab.ui.control.Label
        SafetyFactorFractureEditField   matlab.ui.control.NumericEditField
        SafetyFactorFractureEditFieldLabel  matlab.ui.control.Label
        SafetyFactorYieldEditField      matlab.ui.control.NumericEditField
        SafetyFactorYieldEditFieldLabel  matlab.ui.control.Label
        DesignContraintsLabel           matlab.ui.control.Label
        StressAnalysisButtonGroup       matlab.ui.container.ButtonGroup
        TrescaButton                    matlab.ui.control.RadioButton
        vonMisesButton                  matlab.ui.control.RadioButton
        LoadingConditionsLabel          matlab.ui.control.Label
        GeometricPropertiesLabel        matlab.ui.control.Label
        BendingMomentEditField          matlab.ui.control.NumericEditField
        BendingMomentEditFieldLabel     matlab.ui.control.Label
        TorqueNmEditField               matlab.ui.control.NumericEditField
        TorqueNmEditFieldLabel          matlab.ui.control.Label
        AxialForceNEditField            matlab.ui.control.NumericEditField
        AxialForceNEditFieldLabel       matlab.ui.control.Label
        LengthmmEditField               matlab.ui.control.NumericEditField
        LengthmmEditFieldLabel          matlab.ui.control.Label
        RadiusmmEditField               matlab.ui.control.NumericEditField
        RadiusmmEditFieldLabel          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: SolveButton, TorqueNmEditField
        function SolveButtonPushed(app, event)

            radius = app.RadiusmmEditField.Value;
            length = app.LengthmmEditField.Value;

            axial_force = app.AxialForceNEditField.Value;
            bending_moment = app.BendingMomentEditField.Value;
            torque = app.TorqueNmEditField.Value;

            yield_strength = app.YieldStrengthMPaEditField.Value;
            crit_fracture = app.CriticalFractureToughnessEditField.Value;

            safety_yield = app.SafetyFactorYieldEditField.Value;
            safety_fracture = app.SafetyFactorFractureEditField.Value;


            stresses_result = stressHandle(radius, axial_force, bending_moment, torque);
            eff_stress_result = effHandle(principal_stress,stress_type);
            safety_result = safetyHandle();

            assignin('base','result',stresses_result)
            assignin('base','eff_stress',eff_stress_result)
            assignin('base','safety_factor',safety_result)

            closereq

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Shaft Calculator - Mechanical Behaviour of a Shaft under Combined Loading';
            app.RadiusmmEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusmmEditFieldLabel.Text = 'Radius (mm)';
            app.RadiusmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthmmEditFieldLabel = uilabel(app.UIFigure);
            app.LengthmmEditFieldLabel.Text = 'Length (mm)';
            app.LengthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AxialForceNEditFieldLabel = uilabel(app.UIFigure);
            app.AxialForceNEditFieldLabel.Text = 'Axial Force (N)';
            app.AxialForceNEditField = uieditfield(app.UIFigure, 'numeric');
            app.TorqueNmEditFieldLabel = uilabel(app.UIFigure);
            app.TorqueNmEditFieldLabel.Text = 'Torque (Nm)';
            app.TorqueNmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TorqueNmEditField.ValueChangedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.BendingMomentEditFieldLabel = uilabel(app.UIFigure);
            app.BendingMomentEditFieldLabel.Text = 'Bending Moment';
            app.BendingMomentEditField = uieditfield(app.UIFigure, 'numeric');
            app.GeometricPropertiesLabel = uilabel(app.UIFigure);
            app.GeometricPropertiesLabel.Text = 'Geometric Properties';
            app.LoadingConditionsLabel = uilabel(app.UIFigure);
            app.LoadingConditionsLabel.Text = 'Loading Conditions';
            app.StressAnalysisButtonGroup = uibuttongroup(app.UIFigure);
            app.StressAnalysisButtonGroup.Title = 'Stress Analysis';
            app.vonMisesButton = uiradiobutton(app.StressAnalysisButtonGroup);
            app.vonMisesButton.Text = 'von Mises';
            app.vonMisesButton.Value = true;
            app.TrescaButton = uiradiobutton(app.StressAnalysisButtonGroup);
            app.TrescaButton.Text = 'Tresca';
            app.DesignContraintsLabel = uilabel(app.UIFigure);
            app.DesignContraintsLabel.Text = 'Design Contraints';
            app.SafetyFactorYieldEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyFactorYieldEditFieldLabel.Text = 'Safety Factor (Yield)';
            app.SafetyFactorYieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafetyFactorFractureEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyFactorFractureEditFieldLabel.Text = 'Safety Factor (Fracture)';
            app.SafetyFactorFractureEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaterialPropertiesLabel = uilabel(app.UIFigure);
            app.MaterialPropertiesLabel.Text = 'Material Properties';
            app.YieldStrengthMPaEditFieldLabel = uilabel(app.UIFigure);
            app.YieldStrengthMPaEditFieldLabel.Text = 'Yield Strength (MPa)';
            app.YieldStrengthMPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.CriticalFractureToughnessEditFieldLabel = uilabel(app.UIFigure);
            app.CriticalFractureToughnessEditFieldLabel.Text = 'Critical Fracture Toughness';
            app.CriticalFractureToughnessEditField = uieditfield(app.UIFigure, 'numeric');
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shaft_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end